classdef GUI_selectPlots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AveragetracesbyexperimentCheckBox_2  matlab.ui.control.CheckBox
        AveragetracesbyexperimentCheckBox  matlab.ui.control.CheckBox
        peakMatrixCheckBox              matlab.ui.control.CheckBox
        avgTracesCheckBox               matlab.ui.control.CheckBox
        CompilleDataLabel               matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        PPRsCheckBox                    matlab.ui.control.CheckBox
        AverageEPSCsbyconditionCheckBox  matlab.ui.control.CheckBox
        PlotstoGenerateLabel            matlab.ui.control.Label
        ThirdConditionDropDown          matlab.ui.control.DropDown
        ThirdConditionDropDownLabel     matlab.ui.control.Label
        SecondConditionDropDown         matlab.ui.control.DropDown
        SecondConditionDropDownLabel    matlab.ui.control.Label
        BaselineConditionDropDown       matlab.ui.control.DropDown
        BaselineConditionDropDownLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        allDrugs = {'None','Control','AgaTK','ConoGVIA', ...
            '10uM_AMN082','100uM_AMN082','CdCl2','Muscarine'};
        selectedPlots
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    app.UIFigure.Position = [   652   534   560   215];
            
            app.BaselineConditionDropDown.Items = ...
        {'Control','AgaTK','ConoGVIA','Muscarine'};

    app.BaselineConditionDropDown.Value = 'Control';

    drugs = app.allDrugs;
    drugs(strcmp(drugs, app.BaselineConditionDropDown.Value)) = [];

    app.SecondConditionDropDown.Items = drugs;
    app.SecondConditionDropDown.Value = 'None';

    app.ThirdConditionDropDown.Items = {'None'};
    app.ThirdConditionDropDown.Value = 'None';

        end

        % Value changed function: BaselineConditionDropDown
        function baselineCondDropDownValueChanged(app, event)
            baselineCond = app.BaselineConditionDropDown.Value;
           
            % Remove already-selected condition
            drugs = app.allDrugs;
            drugs(strcmp(app.allDrugs, baselineCond)) = [];

            % Update second dropdown
            app.SecondConditionDropDown.Items = drugs;

            % Reset value
            app.SecondConditionDropDown.Value = 'None';

            % Reset third dropdown too
            app.ThirdConditionDropDown.Items = {'None'};
            app.ThirdConditionDropDown.Value = 'None';

        end

        % Value changed function: SecondConditionDropDown
        function SecondCondDropDownValueChanged(app, event)

    baselineCond = app.BaselineConditionDropDown.Value;
    secondCond = app.SecondConditionDropDown.Value;

    drugs = app.allDrugs;

    % Remove already-used entries
    drugs(strcmp(drugs, baselineCond)) = [];
    drugs(strcmp(drugs, secondCond)) = [];

    % Update third dropdown
    app.ThirdConditionDropDown.Items = drugs;

    % Reset third dropdown value
    app.ThirdConditionDropDown.Value = 'None';

        end

        % Button pushed function: GenerateButton
        function plotButtonPush(app, event)
            clear app.selectedPlots
            evalin('base', 'clearvars mainPharm PeakData waveData')


    % ---- Get selected conditions ----
    baselineCond = app.BaselineConditionDropDown.Value;
    secondCond   = app.SecondConditionDropDown.Value;
    thirdCond    = app.ThirdConditionDropDown.Value;

    % ---- establish conditions ----
    [condList, mainPharm] = buildConditionList(baselineCond, secondCond, thirdCond);
    assignin('base', 'mainPharm', mainPharm);
    assignin('base', 'condList', condList);

    % ---- Build compiled data (pulls the main Aga/Cono.mat dataset) ----
    compiledData = buildCompiledData(mainPharm, condList); 

% ---- Look at selections ----
                if app.PPRsCheckBox.Value
                    app.selectedPlots{end+1} = 'PPR data';
                end

                if app.AverageEPSCsbyconditionCheckBox.Value
                    app.selectedPlots{end+1} = 'Compile Avg EPSCs Data';
                end
          
                if app.peakMatrixCheckBox.Value
                    app.selectedPlots{end+1} = 'Peaks data';
                end

                if app.avgTracesCheckBox.Value
                    app.selectedPlots{end+1} = 'EPSC traces data';
                end

                if app.AveragetracesbyexperimentCheckBox.Value
                    app.selectedPlots{end+1} = 'Plot ESPC condition averages';
                end

                if app.AveragetracesbyexperimentCheckBox_2.Value
                    app.selectedPlots{end+1} = 'Plot EPSC avgs by cell';
                end

    % ---- Generate selected data  ----
    for k = 1:numel(app.selectedPlots)

        switch app.selectedPlots{k}

            case 'PPR data'
                extractPPRs(compiledData, condList);

            case 'Plot EPSC condition averages'
                WaveData = extractAverageEPSCs(compiledData, condList);
                plotAverageEPSCs(WaveData);

            case 'Peaks data'
                extractEPSCPeaks(compiledData, condList)

            case 'Compile Avg EPSCs Data'
                extractAverageEPSCs(compiledData, condList);

            case 'Plot EPSC avgs by cell'
                    ComparisonData = buildEPSCComparisonData(compiledData, condList);
                    ComparisonData = addSourceInfoToComparisonData(ComparisonData);
                    assignin('base', 'ComparisonData', ComparisonData);
                    plotIndividualAvgdWaves(ComparisonData);
        end
    end

    close(app.UIFigure);
    fprintf('Done!\n')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaselineConditionDropDownLabel = uilabel(app.UIFigure);
            app.BaselineConditionDropDownLabel.Text = 'Baseline Condition';
            app.BaselineConditionDropDown = uidropdown(app.UIFigure);
            app.BaselineConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @baselineCondDropDownValueChanged, true);
            app.SecondConditionDropDownLabel = uilabel(app.UIFigure);
            app.SecondConditionDropDownLabel.Text = 'Second Condition';
            app.SecondConditionDropDown = uidropdown(app.UIFigure);
            app.SecondConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondCondDropDownValueChanged, true);
            app.ThirdConditionDropDownLabel = uilabel(app.UIFigure);
            app.ThirdConditionDropDownLabel.Text = 'Third Condition';
            app.ThirdConditionDropDown = uidropdown(app.UIFigure);
            app.PlotstoGenerateLabel = uilabel(app.UIFigure);
            app.PlotstoGenerateLabel.Text = 'Plots to Generate: ';
            app.AverageEPSCsbyconditionCheckBox = uicheckbox(app.UIFigure);
            app.AverageEPSCsbyconditionCheckBox.Text = {'Average EPSCs'; ' by condition'};
            app.PPRsCheckBox = uicheckbox(app.UIFigure);
            app.PPRsCheckBox.Text = 'PPRs';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPush, true);
            app.GenerateButton.Text = 'Generate';
            app.CompilleDataLabel = uilabel(app.UIFigure);
            app.CompilleDataLabel.Text = 'Compille Data:';
            app.avgTracesCheckBox = uicheckbox(app.UIFigure);
            app.avgTracesCheckBox.Text = 'Average EPSCs';
            app.peakMatrixCheckBox = uicheckbox(app.UIFigure);
            app.peakMatrixCheckBox.Text = 'Average Peaks';
            app.AveragetracesbyexperimentCheckBox = uicheckbox(app.UIFigure);
            app.AveragetracesbyexperimentCheckBox.Text = {'Average traces '; 'by experiment'};
            app.AveragetracesbyexperimentCheckBox_2 = uicheckbox(app.UIFigure);
            app.AveragetracesbyexperimentCheckBox_2.Text = {'Average traces '; 'by experiment'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_selectPlots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end