classdef GUI_chiayuAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DoneButton                  matlab.ui.control.Button
        StimLocationEditField       matlab.ui.control.NumericEditField
        StimLocationEditFieldLabel  matlab.ui.control.Label
        IsthexaxisinButtonGroup     matlab.ui.container.ButtonGroup
        pointsRadioButton           matlab.ui.control.RadioButton
        msRadioButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        stimLoc
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [776   591   190   172];
            uiwait(app.UIFigure); % waits until done button is pressed
        end

        % Button pushed function: DoneButton
        function doneButton(app, event)
               app.UIFigure.Visible = 'off';
         app.stimLoc = app.StimLocationEditField.Value;
         
               if app.pointsRadioButton.Value 
                   app.stimLoc = app.stimLoc / 10;
               end

               uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IsthexaxisinButtonGroup = uibuttongroup(app.UIFigure);
            app.IsthexaxisinButtonGroup.Title = 'Is the x-axis in:';
            app.msRadioButton = uiradiobutton(app.IsthexaxisinButtonGroup);
            app.msRadioButton.Text = 'ms';
            app.msRadioButton.Value = true;
            app.pointsRadioButton = uiradiobutton(app.IsthexaxisinButtonGroup);
            app.pointsRadioButton.Text = 'points';
            app.StimLocationEditFieldLabel = uilabel(app.UIFigure);
            app.StimLocationEditFieldLabel.Text = 'Stim Location';
            app.StimLocationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @doneButton, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_chiayuAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end